classdef dfInspectRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AudioTime                   matlab.ui.control.UIAxes
        MicrophoneDataListBoxLabel  matlab.ui.control.Label
        TrialListBox                matlab.ui.control.ListBox
        SelectFilesButton           matlab.ui.control.Button
        FilesPathEditFieldLabel     matlab.ui.control.Label
        FilesPathEditField          matlab.ui.control.EditField
        curSessLabel                matlab.ui.control.Label
        AudioSpectrum               matlab.ui.control.UIAxes
        UpArrow                     matlab.ui.control.Button
        DnArrow                     matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
    end


    properties (Access = private)
        defaultPath   % Where to start looking for recorded data
        fullFilePath
        AllRecVar     % All variables and data structures saved as part of a recorded run.
        AudapData     % Data set recorded by Audapter
        
        fs            % Sampling rate of the recording
        curSess       % Which Subject, and run
        numTrial      % number of trials
        trialNames    % Names of trials
        trialType     % Control (0)/ Perturbed (1)
        expTrig
        audTrig
        
        curTrialName
        curMicData
        curPertOnset
        curPertOffset
        curPertVis
    end

    methods (Access = private)
        
        function confirmUseableFile(app)
            if isfile(app.fullFilePath)
                loadAudapFiles(app, 0)
            else
                fprintf('Not a useable file')
            end
        end
    
        function loadAudapFiles(app, event)
            
            app.FilesPathEditField.Value = app.fullFilePath;
            
            load(app.fullFilePath); % Loads the variable 'DRF';
            app.AllRecVar = DRF;
            app.AudapData = DRF.rawData;
            
            app.fs         = DRF.expParam.sRateAnal;
            app.curSess    = DRF.expParam.curSess;
            app.numTrial   = DRF.expParam.numTrial;
            
            if isfield(DRF.expParam, 'trialType')
                app.trialType = DRF.expParam.trialType;
            else
                app.trialType = zeros(app.numTrial, 1);
            end
            
            if isfield(DRF.expParam, 'trigs')
                app.expTrig = DRF.expParam.trigs(:,:,1); 
                app.audTrig = DRF.expParam.trigs(:,:,3);
            else
                app.expTrig = repmat([0 0], app.numTrial, 1);
                app.audTrig = repmat([0 0], app.numTrial, 1);
            end
            
            app.trialNames = {};
            for ii = 1:app.numTrial
                append = '';
                if app.trialType(ii) == 1
                    append = '***';
                end              
                app.trialNames{ii} = ['Trial ' num2str(ii) append];
            end
            
            app.curSessLabel.Text  = app.curSess;
            app.TrialListBox.Items = app.trialNames;
            app.TrialListBox.Value = app.trialNames(1);
                      
            
            switchCurAudio(app, event)
        end
        
        function qProc = identifyQualityIssues(app)
            
            audioMic  = app.curMicData;
            trialLen  = length(audioMic);
            trialTime = trialLen/app.fs;
            time      = linspace(0, trialTime, trialLen);
            
            envCutoff = 40;
            thresh    = 0.3;
            [B, A]    = butter(4, envCutoff/(app.fs/2));
            qProc.env = filter(B, A, abs(app.curMicData));
            
            qProc.maxEnv        = max(qProc.env);
            qProc.threshIdx     = find(qProc.env > thresh*qProc.maxEnv);
            qProc.voiceOnsetInd = qProc.threshIdx(1);
            qProc.voiceOnsetTim = time(qProc.voiceOnsetInd);
            
            qProc.spchLB = app.curPertOnset  - 0.5*app.fs; qProc.spchLBt = qProc.spchLB/app.fs;
            qProc.spchRB = app.curPertOffset + 1.0*app.fs; qProc.spchRBt = qProc.spchRB/app.fs;
            
            if qProc.spchLB < qProc.voiceOnsetInd
%                 fprintf('Subject Started Late')
            end
        end
        
        function [p, f, t] = calcSpectrogram(app)
            audioMic = app.curMicData;
                       
            trialLen  = length(audioMic);
            trialTime = trialLen/app.fs;
            
            win      = 0.05;
            winP     = win*app.fs;
            pOV      = 50;
            nOV      = pOV*winP;
            tStep    = round(winP*(1-pOV));
            trialWin = round(1:tStep:(trialLen-winP));
            numWin   = length(trialWin);
            
            NFFT = 2^nextpow2(winP);
           
            [p,f,t] = pspectrum(audioMic, ...
                                app.fs, ...
                                'spectrogram', ...
                                'MinThreshold', ...
                                -90, ...
                                'FrequencyLimits', ...
                                [0 5000]);
            
%             secAudio = zeros(winP, numWin);
%             for ii = 1:numWin
%                 winIdx    = trialWin(ii):trialWin(ii)+ winP-1;
%                 winAudio  = audioMic(winIdx);
%                 secAudio(:, ii) = winAudio;
%             end
%             [p, f] = pwelch(secAudio, winP, nOV, [], app.fs, 'power');
%             [~, pLen] = size(p);
%             t = linspace(0, trialTime, pLen);
        end
        
        function drawCurTrial_Time(app) 
            audioMic = app.curMicData;
            minAudio = min(audioMic);
            maxAudio = max(audioMic);
            
            trialLen  = length(audioMic);
            trialTime = trialLen/app.fs;
            time      = linspace(0, trialTime, trialLen);
            
            qProc = identifyQualityIssues(app);
            
            pertAx    = [app.curPertOnset app.curPertOffset];
            pertAy    = [2 2];
            pertColor = [0 0 1];
            visOption = {'Off', 'On'};
            
            plot(app.AudioTime, time, audioMic, 'k')
            hold(app.AudioTime,'on')
            
            area(app.AudioTime, pertAx, pertAy, -2, 'FaceColor', pertColor, 'EdgeColor', 'none', 'FaceAlpha', 0.25, 'Visible', visOption{app.curPertVis + 1});
            plot(app.AudioTime, time, qProc.env, 'y')
            plot(app.AudioTime, [qProc.voiceOnsetTim qProc.voiceOnsetTim], [-1.1 1.1], 'r--', 'LineWidth', 2)
%             plot(app.AudioTime, [qProc.voiceOnsetTim qProc.voiceOnsetTim], [-1.1 1.1], 'r--', 'LineWidth', 2) 
            hold(app.AudioTime,'off')
            
            app.AudioTime.Title.String   = app.curTrialName;
            app.AudioTime.Title.FontSize = 18;
            app.AudioTime.XLim         = [0 5];
            app.AudioTime.YLim         = [minAudio maxAudio];
        end
        
        function drawCurTrial_Spect(app)

            [p, f, t] = calcSpectrogram(app); 

            surf(app.AudioSpectrum, t, f, 10*log10(p), 'EdgeColor', 'none'); 
            app.AudioSpectrum.XLim     = [0 5];
            app.AudioSpectrum.YLim     = [0 5000];
            app.AudioSpectrum.View     = [0 90];
            app.AudioSpectrum.Colormap = flipud(gray);
        end
        
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function setDefaultParams(app, varargin)
            app.defaultPath = 'E:\Documents\DATA\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';            
            app.FilesPathEditField.Value = app.defaultPath;
            
            if ~isempty(varargin)
                fullPath = varargin{1};
                if exist(fullPath, 'file') == 0
                    fprintf('No file at the path specified!\n')
                    close(app.UIFigure)
                else
                    app.fullFilePath = fullPath;                   
                    confirmUseableFile(app)
                end
            else
                app.fullFilePath = 'W:\Experiments\Dissociating-Role-of-Feedback-in-Voice-Motor-Control\DRF_MN16\SF1\DRF_MN16SF1DRF.mat';
                confirmUseableFile(app)
            end
        end

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            [fileName, filePath] = uigetfile(app.defaultPath);
            
            if fileName ~= 0
                app.fullFilePath = fullfile(filePath, fileName);       
                confirmUseableFile(app)
            end
        end

        % Value changed function: TrialListBox
        function switchCurAudio(app, event)
            app.curTrialName = app.TrialListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curMicData    = app.AudapData(trialIdx).signalIn;
            app.curPertOnset  = app.expTrig(trialIdx, 1);
            app.curPertOffset = app.expTrig(trialIdx, 2);
            app.curPertVis    = app.trialType(trialIdx);       % Visible or not
            
            drawCurTrial_Time(app)
            drawCurTrial_Spect(app)
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDnInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrial
                newtrialIdx = trialIdx + 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end            
        end

        % Button pushed function: PlayAudioButton
        function PlaySelectedAudio(app, event)
            sound(app.curMicData, app.fs)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AudioTime = uiaxes(app.UIFigure);
            title(app.AudioTime, ' ')
            xlabel(app.AudioTime, 'Time (s)')
            app.MicrophoneDataListBoxLabel = uilabel(app.UIFigure);
            app.MicrophoneDataListBoxLabel.Text = 'Microphone Data';
            app.TrialListBox = uilistbox(app.UIFigure);
            app.TrialListBox.Items = {};
            app.TrialListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurAudio, true);
            app.TrialListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.curSessLabel = uilabel(app.UIFigure);
            app.curSessLabel.Text = '';
            app.AudioSpectrum = uiaxes(app.UIFigure);
            title(app.AudioSpectrum, ' ')
            xlabel(app.AudioSpectrum, 'Time (s)')
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDnInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedAudio, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfInspectRawData(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)setDefaultParams(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end